classdef AttendanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainPanel                  matlab.ui.container.Panel
        LogoutButton               matlab.ui.control.Button
        CreateSlotButton           matlab.ui.control.Button
        SelectSlotButton           matlab.ui.control.Button
        SelectSlot                 matlab.ui.control.DropDown
        SlotDropdownDropDownLabel  matlab.ui.control.Label
        AddStudentButton           matlab.ui.control.Button
        StopAttendanceButton       matlab.ui.control.Button
        AttendancePanel            matlab.ui.container.Panel
        CameraAxes                 matlab.ui.control.UIAxes
        TakeAttendanceButton       matlab.ui.control.Button
        LoginPanel                 matlab.ui.container.Panel
        ShowPasswordCheckBox       matlab.ui.control.CheckBox
        NewuserSignupButton        matlab.ui.control.Button
        LoginButton                matlab.ui.control.Button
        Password                   matlab.ui.control.EditField
        Username                   matlab.ui.control.EditField
        PasswordLabel              matlab.ui.control.Label
        LoginLabel                 matlab.ui.control.Label
    end

   properties (Access = public)
        LoggedInUser              % Username after login
        SelectedSlot              % Selected slot name after login
        attendanceDataPath = 'attendance_data'  % Main folder path
    end

    % ===== PRIVATE PROPERTIES =====
    properties (Access = private)
        cam                     % webcam object
        detector                % face detector
        isAttendanceRunning = false
        MarkedStudents = {}     % cell array of registration numbers already recorded this session
        lastUnknownAlertTime = datetime('2000-01-01')  % Initialize with a past date
        realPassword = ''       % Store actual password
        lastDetections          % Map for multi-frame validation
        debugMode = true;       % Set to true for debugging
        facenetModel            % FaceNet model
        facenetInputSize = [160, 160]  % Standard FaceNet input size
    end
    
    methods (Access = private)
        % Get base path of your app
        function path = getBaseFolder(~)
            if isdeployed
                % Standalone execution - use the deployment root
                path = ctfroot;
            else
                % MATLAB execution - use the current file location
                path = fileparts(mfilename('fullpath'));
            end
        end
        
        % Return user's folder and slot file
        function [userFolder, slotFile] = getUserSlotPaths(app)
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            userFolder = fullfile(baseFolder, app.attendanceDataPath, username);
            slotFile = fullfile(userFolder, 'slots.xlsx');
        end
        
        % Return absolute path for any file in attendance_data
        function fullPath = getDataFilePath(app, relativePath)
            baseFolder = app.getBaseFolder();
            fullPath = fullfile(baseFolder, app.attendanceDataPath, relativePath);
        end
        
        % Return path to selected slot folder
        function slotPath = getSlotPath(app)
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            slotName = app.SelectedSlot;
            slotPath = fullfile(baseFolder, app.attendanceDataPath, username, slotName);
        end
        
        % Return path to 'students' folder inside selected slot
        function studentFolder = getStudentBasePath(app)
            slotPath = app.getSlotPath();
            studentFolder = fullfile(slotPath, 'students');
            if ~exist(studentFolder, 'dir')
                mkdir(studentFolder);
            end
        end
        
        % Return full path to a specific student's folder
        function studentPath = getStudentFolder(app, studentName)
            base = app.getStudentBasePath();
            studentPath = fullfile(base, studentName);
        end
        
        % Ensure valid camera axes
        function ax = getSafeCameraAxes(app)
            ax = app.CameraAxes;
            if isempty(ax) || ~isa(ax, 'matlab.ui.control.UIAxes') || ~isgraphics(ax)
                ax = uiaxes(app.AttendancePanel);
                ax.Position = [20 50 400 300];
                title(ax, ''); 
                xlabel(ax, ''); 
                ylabel(ax, '');
                app.CameraAxes = ax;
            end
            ax.Visible = 'on';
        end
        
        % Initialize webcam with better error handling
        function ok = initCamera(app)
            ok = false;
            try
                % Try to release any previous camera first
                try
                    app.CameraAxes.Visible = 'off';
                    title(app.CameraAxes, '');
                catch
                end

                % Create a fresh webcam object
                app.cam = webcam;
                
                % Test if camera is actually working
                try
                    testFrame = snapshot(app.cam);
                    if isempty(testFrame) || all(testFrame(:) == 0)
                        error('Camera returned blank frame');
                    end
                    ok = true;
                catch
                    error('Camera test frame failed');
                end
            catch ME
                errMsg = sprintf('Unable to access webcam:\n%s', ME.message);
                if isdeployed
                    errordlg(errMsg, 'Camera Error');
                else
                    uialert(app.UIFigure, errMsg, 'Camera Error', 'Icon', 'error');
                end
                ok = false;
            end
        end

        % Release camera
        function releaseCamera(app)
            try
                if ~isempty(app.cam)
                    try
                        delete(app.cam);
                    catch
                        try
                            clear app.cam;
                        catch
                        end
                    end
                    app.cam = [];
                end
            catch
            end

            % Remove any old image from axes
            try
                if isgraphics(app.CameraAxes)
                    cla(app.CameraAxes);
                end
            catch
            end
        end
        
        % Log message for debugging
        function logMessage(app, message)
            try
                logFile = fullfile(app.getBaseFolder(), 'app.log');
                fid = fopen(logFile, 'a');
                if fid ~= -1
                    fprintf(fid, '%s: %s\n', datestr(now), message);
                    fclose(fid);
                else
                    % If file can't be opened, display in command window for debugging
                    if ~isdeployed
                        disp([datestr(now) ': ' message]);
                    end
                end
            catch
                % If anything fails, just display in command window
                if ~isdeployed
                    disp([datestr(now) ': ' message]);
                end
            end
        end
        
        % Download FaceNet model if not available
        function downloadFaceNetModel(app, modelPath)
            try
                % Create a progress dialog
                d = uiprogressdlg(app.UIFigure, 'Title', 'Downloading FaceNet Model',...
                    'Message', 'Downloading FaceNet model from GitHub...', 'Indeterminate', 'on');
                
                % URL to download the model from
                modelUrl = 'https://github.com/Aditya-ztch/Attendance-app-/raw/main/faceNet_model.mat';
                
                % Download the file
                websave(modelPath, modelUrl);
                
                % Close the progress dialog
                close(d);
                
                app.logMessage('FaceNet model downloaded successfully.');
                uialert(app.UIFigure, 'FaceNet model downloaded successfully!', 'Download Complete', 'Icon', 'success');
            catch ME
                % Close the progress dialog if it's still open
                try
                    close(d);
                catch
                end
                
                errorMsg = sprintf('Failed to download FaceNet model: %s', ME.message);
                app.logMessage(errorMsg);
                uialert(app.UIFigure, errorMsg, 'Download Error', 'Icon', 'error');
                rethrow(ME);
            end
        end
        
        % Load FaceNet model with enhanced error handling
        function loadFaceNetModel(app)
            try
                if isdeployed
                    basePath = ctfroot;
                    modelPath = fullfile(basePath, 'faceNet_model.mat');
                else
                    modelPath = fullfile(app.getBaseFolder(), 'faceNet_model.mat');
                end

                app.logMessage(sprintf('Looking for FaceNet model at: %s', modelPath));
                
                % Check if model exists, download if not
                if ~exist(modelPath, 'file')
                    app.logMessage('FaceNet model not found. Attempting to download...');
                    app.downloadFaceNetModel(modelPath);
                end
                
                app.logMessage(sprintf('Loading FaceNet model from: %s', modelPath));
                loadedData = load(modelPath);
                
                % Handle different model formats
                if isfield(loadedData, 'net')
                    app.facenetModel = loadedData.net;
                elseif isfield(loadedData, 'facenetModel')
                    app.facenetModel = loadedData.facenetModel;
                else
                    error('Could not find FaceNet model in the loaded file');
                end
                
                app.logMessage('FaceNet model loaded successfully.');

                % Test model with a simple input
                testInput = zeros([app.facenetInputSize, 3]);
                testOutput = app.extractFaceNetEmbedding(testInput);
                app.logMessage(sprintf('Test output size: %s', mat2str(size(testOutput))));
                
            catch ME
                errorMsg = sprintf('Failed to load FaceNet: %s', ME.message);
                app.logMessage(errorMsg);
                
                % Only show alert if we're in a UI context
                try
                    uialert(app.UIFigure, errorMsg, 'Model Error', 'Icon', 'error');
                catch
                    disp(errorMsg);
                end
                
                app.facenetModel = [];
            end
        end
        
        % Preprocess face image for FaceNet
        function processedFace = preprocessFaceForFaceNet(app, faceImage)
            % Convert to RGB if grayscale
            if size(faceImage, 3) == 1
                faceImage = repmat(faceImage, [1, 1, 3]);
            end
            
            % Resize to FaceNet input size
            processedFace = imresize(faceImage, app.facenetInputSize);
            
            % Normalize pixel values to [0, 1]
            processedFace = im2double(processedFace);
            
            % Normalize using ImageNet standards (common for FaceNet)
            meanImg = [0.485, 0.456, 0.406];
            stdImg = [0.229, 0.224, 0.225];
            for i = 1:3
                processedFace(:,:,i) = (processedFace(:,:,i) - meanImg(i)) / stdImg(i);
            end
        end
        
        % Extract FaceNet embeddings with proper model handling
        function embedding = extractFaceNetEmbedding(app, faceImage)
            if isempty(app.facenetModel)
                error('FaceNet model not loaded');
            end
            
            % Preprocess the face
            processedFace = app.preprocessFaceForFaceNet(faceImage);
            
            % Handle different model formats
            if isa(app.facenetModel, 'DAGNetwork') || isa(app.facenetModel, 'SeriesNetwork')
                % For standard networks - use standard predict
                embedding = predict(app.facenetModel, processedFace);
                
            elseif isa(app.facenetModel, 'dlnetwork')
                % For dlnetwork
                processedFaceDL = dlarray(processedFace, 'SSCB');
                embedding = predict(app.facenetModel, processedFaceDL);
                embedding = extractdata(embedding);
                
            else
                % Try generic predict with proper input formatting
                try
                    % Standard format for most networks
                    embedding = predict(app.facenetModel, processedFace);
                catch
                    % Alternative format if standard fails
                    processedFace = permute(processedFace, [3, 2, 1]); % Change to CxWxH
                    processedFace = reshape(processedFace, [1, size(processedFace)]); % Add batch dimension
                    embedding = predict(app.facenetModel, processedFace);
                end
            end
            
            % Ensure embedding is a vector (flatten if needed)
            embedding = embedding(:);
            
            % Normalize embedding to unit length
            if any(embedding) && norm(embedding) > 0 % Only normalize if not all zeros
                embedding = embedding / norm(embedding);
            else
                embedding = zeros(size(embedding));
            end
            
            % Debug output
            if app.debugMode
                app.logMessage(sprintf('Embedding size: %s, Norm: %.4f', mat2str(size(embedding)), norm(embedding)));
            end
        end
        
        % Enhanced face detection with better preprocessing
        function [faceImg, success] = detectAndPreprocessFace(app, frame)
            success = false;
            faceImg = [];
            
            % Convert to grayscale for detection
            grayFrame = rgb2gray(frame);
            
            % Detect faces with multiple classifiers for better accuracy
            try
                % Try frontal face detector first
                bboxes = step(app.detector, grayFrame);
                
                % If no faces found, try profile detector
                if isempty(bboxes)
                    profileDetector = vision.CascadeObjectDetector('ClassificationModel', 'ProfileFace');
                    bboxes = step(profileDetector, grayFrame);
                end
                
                if isempty(bboxes)
                    return;
                end
                
                % Use the largest detected face
                [~, idx] = max(bboxes(:,3).*bboxes(:,4));
                bbox = bboxes(idx, :);
                
                % Expand bounding box slightly to include more context
                expansion = 0.2; % Expand by 20%
                bbox(1) = max(1, bbox(1) - bbox(3)*expansion/2);
                bbox(2) = max(1, bbox(2) - bbox(4)*expansion/2);
                bbox(3) = min(size(frame, 2) - bbox(1), bbox(3)*(1+expansion));
                bbox(4) = min(size(frame, 1) - bbox(2), bbox(4)*(1+expansion));
                
                % Extract face from color image
                faceImg = imcrop(frame, bbox);
                
                success = true;
                
            catch ME
                if app.debugMode
                    app.logMessage(sprintf('Face detection error: %s', ME.message));
                end
            end
        end
        
        % Enhanced feature extraction with consistency checks
        function [features, success] = extractEnhancedFeatures(app, faceImg)
            success = false;
            features = [];
            
            try
                % Extract FaceNet embedding
                faceNetEmbedding = app.extractFaceNetEmbedding(faceImg);
                
                % Check if FaceNet embedding is valid
                if isempty(faceNetEmbedding) || all(faceNetEmbedding == 0)
                    if app.debugMode
                        app.logMessage('Invalid FaceNet embedding');
                    end
                    return;
                end
                
                features = faceNetEmbedding;
                success = true;
                
            catch ME
                if app.debugMode
                    app.logMessage(sprintf('Feature extraction error: %s', ME.message));
                end
            end
        end
        
        % Enhanced matching with adaptive threshold
        function [bestStudent, bestScore, regNo, confidence] = findEnhancedMatch(app, queryFeatures, studentsTbl, studentBase)
            bestStudent = "";
            bestScore = -Inf;
            regNo = "NA";
            confidence = "Low";
            
            % If no students exist, return early
            if ~exist(studentBase, 'dir')
                return;
            end
            
            dirList = dir(studentBase);
            if isempty(dirList) || length(dirList) <= 2
                return;
            end
            
            % Calculate the norm of query features for normalization
            queryNorm = norm(queryFeatures);
            if queryNorm == 0
                return;
            end
            
            % Collect all matches for analysis
            allMatches = {};
            
            for s = 1:length(dirList)
                studentName = dirList(s).name;
                if ~dirList(s).isdir || strcmp(studentName, '.') || strcmp(studentName, '..')
                    continue; 
                end
                
                studentFolder = fullfile(studentBase, studentName);
                % Look for feature files
                featureFiles = dir(fullfile(studentFolder, 'combinedFeatures_*.mat'));
                if isempty(featureFiles), continue; end

                maxSim = -Inf;
                for k = 1:length(featureFiles)
                    try
                        % Load the stored features
                        loadedData = load(fullfile(featureFiles(k).folder, featureFiles(k).name));
                        storedFeatures = loadedData.combinedFeatures;
                        
                        % Normalize stored features
                        storedNorm = norm(storedFeatures);
                        if storedNorm == 0
                            continue;
                        end
                        
                        % Calculate cosine similarity
                        sim = dot(queryFeatures, storedFeatures) / (queryNorm * storedNorm);
                        
                        if sim > maxSim, maxSim = sim; end
                    catch ME
                        if app.debugMode
                            app.logMessage(sprintf('Error loading features for %s: %s', studentName, ME.message));
                        end
                        continue;
                    end
                end
                
                % Store this match
                if maxSim > -Inf
                    allMatches{end+1} = {studentName, maxSim};
                end
                
                % Enhanced debug information
                if app.debugMode
                    app.logMessage(sprintf('Student: %s, Combined Score: %.4f, Best Score: %.4f', studentName, maxSim, bestScore));
                end
                
                if maxSim > bestScore
                    bestScore = maxSim;
                    bestStudent = studentName;
                    
                    % Find registration number
                    if ~isempty(studentsTbl) && any(strcmp(studentsTbl.Properties.VariableNames, 'Name'))
                        idx = find(strcmp(studentsTbl.Name, bestStudent), 1);
                        if ~isempty(idx) && any(strcmp(studentsTbl.Properties.VariableNames, 'RegistrationNumber'))
                            regNo = string(studentsTbl.RegistrationNumber(idx));
                        end
                    end
                end
            end
            
            % Determine confidence level based on score distribution
            if ~isempty(allMatches)
                scores = cellfun(@(x) x{2}, allMatches);
                
                % Adaptive threshold based on score distribution
                if bestScore >= 0.7
                    confidence = "High";
                elseif bestScore >= 0.5
                    confidence = "Medium";
                elseif bestScore >= 0.3
                    confidence = "Low";
                else
                    confidence = "Very Low";
                    bestStudent = "";
                    bestScore = -Inf;
                end
                
                % If there are multiple matches with similar scores, reduce confidence
                if length(scores) > 1
                    sortedScores = sort(scores, 'descend');
                    if sortedScores(1) - sortedScores(2) < 0.1  % Small margin
                        confidence = "Low (Multiple Matches)";
                    end
                end
            end
        end
        
       function quality = checkFaceQuality(app, faceImg)
    % Enhanced quality assessment with more lenient thresholds
    quality = 0;
    
    if isempty(faceImg)
        app.logMessage('Quality check failed: Empty face image');
        return;
    end
    
    % Check face size
    [h, w, ~] = size(faceImg);
    if h < 40 || w < 40  % Reduced from 50 to 40
        app.logMessage(sprintf('Quality check failed: Face too small (%dx%d)', h, w));
        return;
    end
    
    % Convert to grayscale if needed
    if size(faceImg, 3) == 3
        grayFace = rgb2gray(faceImg);
    else
        grayFace = faceImg;
    end
    
    % Check contrast using standard deviation of intensity
    contrast = std(double(grayFace(:)));
    if contrast < 15  % Reduced from 20 to 15
        app.logMessage(sprintf('Quality check failed: Low contrast (%.2f)', contrast));
        return;
    end
    
    % Check sharpness using variance of Laplacian
    try
        laplacianKernel = [0 1 0; 1 -4 1; 0 1 0];
        laplacianResponse = conv2(double(grayFace), double(laplacianKernel), 'same');
        sharpness = var(laplacianResponse(:));
    catch ME
        app.logMessage(sprintf('Sharpness calculation error: %s', ME.message));
        sharpness = 0;
    end
    
    if sharpness < 50  % Reduced from 100 to 50
        app.logMessage(sprintf('Quality check failed: Low sharpness (%.4f)', sharpness));
        return;
    end
    
    % Check if face is too dark or too bright
    meanIntensity = mean(grayFace(:));
    if meanIntensity < 25 || meanIntensity > 230  % Expanded range
        app.logMessage(sprintf('Quality check failed: Improper lighting (mean intensity: %.2f)', meanIntensity));
        return;
    end
    
    quality = 1;  % Good quality
    app.logMessage(sprintf('Quality check passed: Contrast=%.2f, Sharpness=%.4f, Mean Intensity=%.2f', contrast, sharpness, meanIntensity));
end
        
        % Confirm Slot Selection
        function ConfirmSlotSelection(app, ~)
            selected = app.SelectSlot.Value;
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a slot from the dropdown.', 'No Selection', 'Icon', 'warning');
                return;
            end

            % Save selected slot
            app.SelectedSlot = selected;

            % Show main panel for attendance
            app.MainPanel.Visible = 'on';
            app.AttendancePanel.Visible = 'on';

            % Alert user
            uialert(app.UIFigure, ['Slot "' char(selected) '" selected successfully.'], ...
                'Slot Selected', 'Icon', 'success');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   % Only Login visible at start
            app.LoginPanel.Visible = 'on';
            app.MainPanel.Visible = 'off';
            app.AttendancePanel.Visible = 'off';
            
            % Hide dropdown components initially
            app.SelectSlot.Visible = 'off';
            app.SlotDropdownDropDownLabel.Visible = 'off';
            
            % Initialize properties
            app.lastDetections = containers.Map;
            
            % Create a debug log file
            try
                logFile = fullfile(app.getBaseFolder(), 'app.log');
                fid = fopen(logFile, 'w');
                if fid ~= -1
                    fprintf(fid, 'Application started at %s\n', datestr(now));
                    fclose(fid);
                end
            catch
                % If file creation fails, continue without logging
            end
            
            % Initialize face detector with better error handling
            try
                app.detector = vision.CascadeObjectDetector(...
                    'ClassificationModel', 'FrontalFaceLBP',...
                    'MinSize', [50, 50],...
                    'MaxSize', [300, 300],...
                    'MergeThreshold', 4);
                
                if app.debugMode
                    app.logMessage('Face detector initialized successfully.');
                end
            catch ME
                errMsg = sprintf('Failed to initialize face detector:\n%s', ME.message);
                if isdeployed
                    errordlg(errMsg, 'Detector Error');
                else
                    uialert(app.UIFigure, errMsg, 'Detector Error', 'Icon', 'error');
                end
            end
            
            % Load FaceNet model
            app.loadFaceNetModel();
            
            % Close any stray figures/uifigures
            try
                figs = findall(0, 'Type', 'figure');
                for i = 1:numel(figs)
                    if figs(i) ~= app.UIFigure
                        try, close(figs(i)); catch, end
                    end
                end
                uifigs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(uifigs)
                    if uifigs(i) ~= app.UIFigure
                        try, close(uifigs(i)); catch, end
                    end
                end
            catch
            end
            
            % Center window
            try, movegui(app.UIFigure, 'center'); catch, end
            
            % Set initial password masking state
            app.realPassword = '';
            app.ShowPasswordCheckBox.Value = false;
            
            % Attach the real-time masking function to the password field
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            
            % Apply colorful UI scheme with grey backgrounds
            app.UIFigure.Color = [0.5, 0.5, 0.5]; % Medium grey background
            app.LoginPanel.Title = 'Login';
            app.LoginPanel.BackgroundColor = [0.5, 0.5, 0.5]; % Medium grey
            app.LoginPanel.FontSize = 16;
            app.LoginLabel.FontWeight = 'bold';
            app.LoginLabel.FontColor = [0.2, 0.4, 0.8]; % Vibrant blue
            app.PasswordLabel.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.Username.BackgroundColor = [1, 1, 1]; % White
            app.Username.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.Password.BackgroundColor = [1, 1, 1]; % White
            app.Password.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.LoginButton.BackgroundColor = [0.2, 0.4, 0.8]; % Vibrant blue
            app.LoginButton.FontColor = [1, 1, 1]; % White text
            app.NewuserSignupButton.BackgroundColor = [0.2, 0.4, 0.8]; % Vibrant blue
            app.NewuserSignupButton.FontColor = [1, 1, 1]; % White text
            app.NewuserSignupButton.Text = 'New User? Sign Up';
            app.ShowPasswordCheckBox.Text = 'Show Password';
            app.ShowPasswordCheckBox.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.MainPanel.Title = 'Main Menu';
            app.MainPanel.BackgroundColor = [0.5, 0.5, 0.5]; % Medium grey
            app.TakeAttendanceButton.BackgroundColor = [0.9, 0.5, 0.2]; % Orange for primary action
            app.TakeAttendanceButton.FontColor = [1, 1, 1]; % White text
            app.AttendancePanel.BackgroundColor = [0.5, 0.5, 0.5]; % Medium grey
            title(app.CameraAxes, 'Camera Feed', 'Color', [0.2, 0.4, 0.8]); % Blue title
            xlabel(app.CameraAxes, '')
            ylabel(app.CameraAxes, '')
            zlabel(app.CameraAxes, '')
            app.CameraAxes.Box = 'on';
            app.CameraAxes.XColor = [0.2, 0.4, 0.8]; % Blue border
            app.CameraAxes.YColor = [0.2, 0.4, 0.8]; % Blue border
            app.StopAttendanceButton.BackgroundColor = [0.2, 0.4, 0.8]; % Blue
            app.StopAttendanceButton.FontColor = [1, 1, 1]; % White text
            app.AddStudentButton.BackgroundColor = [0.2, 0.4, 0.8]; % Blue
            app.AddStudentButton.FontColor = [1, 1, 1]; % White text
            app.SlotDropdownDropDownLabel.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.SlotDropdownDropDownLabel.Text = 'Select Slot';
            app.SelectSlot.BackgroundColor = [0.3, 0.7, 0.5]; % Green
            app.SelectSlot.FontColor = [0.2, 0.2, 0.2]; % Dark gray
            app.SelectSlotButton.BackgroundColor = [0.2, 0.4, 0.8]; % Blue
            app.SelectSlotButton.FontColor = [1, 1, 1]; % White text
            app.CreateSlotButton.BackgroundColor = [0.2, 0.4, 0.8]; % Blue
            app.CreateSlotButton.FontColor = [1, 1, 1]; % White text
            app.CreateSlotButton.Text = 'Create Slot';
            app.LogoutButton.BackgroundColor = [0.9, 0.5, 0.2]; % Orange for emphasis
            app.LogoutButton.FontColor = [1, 1, 1]; % White text
            
            
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
  % Close stray figures/uifigures except app window
            try
                figs = findall(0, 'Type', 'figure');
                for i = 1:numel(figs)
                    if figs(i) ~= app.UIFigure
                        try, close(figs(i)); catch, end
                    end
                end
                uifigs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(uifigs)
                    if uifigs(i) ~= app.UIFigure
                        try, close(uifigs(i)); catch, end
                    end
                end
            catch
            end
            
            % Read inputs
            username = app.Username.Value;
            password = app.realPassword;
            
            % STRICT early return
            if isempty(username) || isempty(strtrim(char(string(username))))
                uialert(app.UIFigure, 'Please enter your username.', 'Login', 'Icon', 'warning');
                return;
            end
            if isempty(password) || isempty(strtrim(char(string(password))))
                uialert(app.UIFigure, 'Please enter your password.', 'Login', 'Icon', 'warning');
                return;
            end
            
            % Verify credentials - FIXED PATH
            loginFile = fullfile(app.getBaseFolder(), app.attendanceDataPath, 'login_data.xlsx');
            
            % Debug output
            if app.debugMode
                app.logMessage(sprintf('Looking for login file at: %s', loginFile));
            end
            
            if ~exist(loginFile, 'file')
                uialert(app.UIFigure, 'Login data not found. Please sign up first.', 'Error', 'Icon', 'error');
                return;
            end
            
            try
                data = readcell(loginFile);
            catch
                uialert(app.UIFigure, 'Unable to read login data file.', 'File Error', 'Icon', 'error');
                return;
            end
            
            isValid = false;
            if size(data,1) >= 2
                for i = 2:size(data, 1)
                    savedUsername = strtrim(string(data{i,1}));
                    savedPassword = strtrim(string(data{i,2}));
                    if strcmp(savedUsername, string(username)) && strcmp(savedPassword, string(password))
                        isValid = true;
                        break;
                    end
                end
            end
            if ~isValid
                uialert(app.UIFigure, 'Invalid username or password.', 'Login Failed', 'Icon', 'error');
                return;
            end
            
            % SUCCESS: Disable login panel, show main
            app.LoggedInUser = username;
            app.LoginPanel.Enable = 'off';   % disable login panel inputs
            app.MainPanel.Visible = 'on';    
            app.AttendancePanel.Visible = 'off';
            
            % Prepare user folders - FIXED PATH
            userPath = fullfile(app.getBaseFolder(), app.attendanceDataPath, username);
            if ~exist(userPath, 'dir')
                mkdir(userPath);
            end
            
            % Load slots into dropdown
            userSlotFile = fullfile(userPath, 'slots.xlsx');
            try
                if exist(userSlotFile, 'file')
                    slots = readcell(userSlotFile);
                    if size(slots,1) > 1
                        slots = slots(2:end, 1);
                        slots = slots(~cellfun('isempty', slots));
                        app.SelectSlot.Items = string(slots);
                    else
                        app.SelectSlot.Items = {};
                    end
                else
                    app.SelectSlot.Items = {};
                end
            catch
                app.SelectSlot.Items = {};
            end
            
            % Success alert
            try
                uialert(app.UIFigure, ...
                    ['Welcome, ' char(username) '! Select or create a slot to continue.'], ...
                    'Login Successful', 'Icon', 'success');
            catch
            end
        end

        % Button pushed function: NewuserSignupButton
        function NewuserSignupButtonPushed(app, event)
    
    % Check if a signup dialog is already open
    existingFigs = findall(0, 'Type', 'uifigure', 'Name', 'New User Registration');
    if ~isempty(existingFigs)
        figure(existingFigs(1)); % Bring existing dialog to front
        return;
    end
    
    try
        % Create a modal uifigure for signup dialog
        dlg = uifigure('Name', 'New User Registration', ...
            'Position', [100 100 400 350], ...
            'Resize', 'off', ...
            'CloseRequestFcn', @(~, ~) delete(dlg), ...
            'AutoResizeChildren', 'off');
        
        % Center the dialog on screen
        movegui(dlg, 'center');
        
        % Title
        uilabel(dlg, ...
            'Text', 'New User Registration', ...
            'Position', [50 310 300 30], ...
            'FontSize', 16, ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'center');
        
        % Username field (mandatory)
        uilabel(dlg, ...
            'Text', 'Username*:', ...
            'Position', [30 270 100 25], ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left');
        usernameField = uieditfield(dlg, 'text', ...
            'Position', [140 270 200 25]);
        
        % Password field (mandatory)
        uilabel(dlg, ...
            'Text', 'Password*:', ...
            'Position', [30 230 100 25], ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left');
        passwordField = uieditfield(dlg, 'text', ...
            'Position', [140 230 200 25]);
        
        % Full Name field (mandatory)
        uilabel(dlg, ...
            'Text', 'Full Name*:', ...
            'Position', [30 190 100 25], ...
            'FontWeight', 'bold', ...
            'HorizontalAlignment', 'left');
        fullnameField = uieditfield(dlg, 'text', ...
            'Position', [140 190 200 25]);
        
        % Mobile field (optional)
        uilabel(dlg, ...
            'Text', 'Mobile:', ...
            'Position', [30 150 100 25], ...
            'HorizontalAlignment', 'left');
        mobileField = uieditfield(dlg, 'text', ...
            'Position', [140 150 200 25]);
        
        % Description field (optional)
        uilabel(dlg, ...
            'Text', 'Description:', ...
            'Position', [30 110 100 25], ...
            'HorizontalAlignment', 'left');
        descField = uieditfield(dlg, 'text', ...
            'Position', [140 80 200 50], ...
            'HorizontalAlignment', 'left');
        
        % Required fields note
        uilabel(dlg, ...
            'Text', '* Required fields', ...
            'Position', [30 50 200 25], ...
            'FontAngle', 'italic', ...
            'FontSize', 10, ...
            'HorizontalAlignment', 'left');
        
        % Cancel button
        uibutton(dlg, 'push', ...
            'Text', 'Cancel', ...
            'Position', [80 20 100 30], ...
            'BackgroundColor', [0.9 0.9 0.9], ...
            'ButtonPushedFcn', @(~, ~) cancelCallback(dlg));
        
        % Register button
        uibutton(dlg, 'push', ...
            'Text', 'Register', ...
            'Position', [220 20 100 30], ...
            'BackgroundColor', [0.4 0.7 0.4], ...
            'FontColor', [1 1 1], ...
            'ButtonPushedFcn', @(~, ~) registerCallback(dlg, usernameField, passwordField, fullnameField, mobileField, descField));
        
        % Make dialog modal
        uiwait(dlg);
        
    catch ME
        % Log and display error
        app.logMessage(sprintf('Error creating signup dialog: %s', ME.message));
        uialert(app.UIFigure, sprintf('Error creating signup dialog: %s', ME.message), ...
            'Dialog Error', 'Icon', 'error');
        if exist('dlg', 'var') && isvalid(dlg)
            delete(dlg);
        end
    end
    
    % Cancel button callback
    function cancelCallback(d)
        delete(d); % Close the signup dialog
    end
    
    % Register button callback
    function registerCallback(d, usernameField, passwordField, fullnameField, mobileField, descField)
        try
            % Get values from fields
            username = strtrim(usernameField.Value);
            password = strtrim(passwordField.Value);
            fullname = strtrim(fullnameField.Value);
            mobile = strtrim(mobileField.Value);
            description = strtrim(descField.Value);
            
            % Validate required fields
            if isempty(username) || isempty(password) || isempty(fullname)
                uialert(d, 'Username, Password, and Full Name are required.', ...
                    'Validation Error', 'Icon', 'warning');
                return;
            end
            
            % Ensure login data directory exists
            loginFile = fullfile(app.getBaseFolder(), app.attendanceDataPath, 'login_data.xlsx');
            loginDataDir = fileparts(loginFile);
            if ~exist(loginDataDir, 'dir')
                mkdir(loginDataDir);
            end
            
            % Define the expected headers
            expectedHeaders = {'Username', 'Password', 'FullName', 'Mobile', 'Description'};
            
            % Check if file exists and read existing data
            if exist(loginFile, 'file')
                try
                    existingData = readcell(loginFile);
                    
                    % Check if existing data has the correct number of columns
                    if ~isempty(existingData) && size(existingData, 2) ~= length(expectedHeaders)
                        % If columns don't match, create new data with correct headers
                        existingData = expectedHeaders;
                    elseif isempty(existingData)
                        % If file is empty, add headers
                        existingData = expectedHeaders;
                    else
                        % Replace any missing values with empty strings
                        for i = 1:size(existingData, 1)
                            for j = 1:size(existingData, 2)
                                if ismissing(existingData{i, j})
                                    existingData{i, j} = '';
                                end
                            end
                        end
                    end
                catch
                    % If file is corrupt, create new data with headers
                    existingData = expectedHeaders;
                end
            else
                % If file doesn't exist, create new data with headers
                existingData = expectedHeaders;
            end
            
            % Check for existing username (skip header row)
            if size(existingData, 1) > 1
                existingUsernames = existingData(2:end, 1);
                if any(strcmpi(existingUsernames, username))
                    uialert(d, 'Username already exists.', 'Signup Error', 'Icon', 'warning');
                    return;
                end
            end
            
            % Create new entry with all fields
            newEntry = {username, password, fullname, mobile, description};
            
            % Replace any empty values with empty strings to avoid missing values
            for i = 1:length(newEntry)
                if isempty(newEntry{i})
                    newEntry{i} = '';
                end
            end
            
            % Append new user credentials
            writecell([existingData; newEntry], loginFile);
            
            % Log success
            app.logMessage(sprintf('User %s registered successfully.', username));
            
            % Close the signup dialog
            delete(d);
            
            % Show success message
            uialert(app.UIFigure, 'Registration successful! You can now log in.', ...
                'Success', 'Icon', 'success');
            
        catch ME
            % Log and display detailed error
            errMsg = sprintf('Error processing registration: %s\nFile: %s', ME.message, loginFile);
            app.logMessage(errMsg);
            uialert(d, errMsg, 'Registration Error', 'Icon', 'error');
        end
    end

        end

        % Button pushed function: CreateSlotButton
        function CreateSlotButtonPushed(app, event)
         % Get slot name with proper cancel handling
            answer = inputdlg({'Enter new slot name:'}, 'Create Slot', [1, 50], {''});
            
            % Check if user clicked Cancel or closed dialog
            if isempty(answer)
                return;
            end
            
            % Check if user entered empty string
            if isempty(answer{1}) || isempty(strtrim(answer{1}))
                uialert(app.UIFigure, 'Slot name cannot be empty.', 'Error', 'Icon', 'warning');
                return;
            end
            
            newSlot = string(strtrim(answer{1}));
            if newSlot == "" || strlength(newSlot) == 0
                uialert(app.UIFigure, 'Slot name cannot be empty.', 'Error', 'Icon', 'warning');
                return;
            end
            
            % Fixed invalid chars
            invalidChars = ['<', '>', ':', '"', '/', '\', '|', '?', '*'];
            if any(ismember(char(newSlot), invalidChars))
                uialert(app.UIFigure, 'Slot name contains invalid characters: \ / : * ? " < > |', 'Invalid Name', 'Icon', 'warning');
                return;
            end
            
            % Use absolute path - FIXED
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            userFolder = fullfile(baseFolder, app.attendanceDataPath, username);
            slotFile = fullfile(userFolder, 'slots.xlsx');
            slotFolder = fullfile(userFolder, char(newSlot));
            
            if ~exist(userFolder, 'dir')
                try
                    mkdir(userFolder);
                catch
                    uialert(app.UIFigure, 'Could not create user folder.', 'Folder Error', 'Icon', 'error');
                end
            end
            
            if exist(slotFolder, 'dir')
                uialert(app.UIFigure, 'Slot already exists.', 'Duplicate Slot', 'Icon', 'warning');
                return;
            end
            
            try
                mkdir(slotFolder);
                mkdir(fullfile(slotFolder, 'students'));
            catch e
                uialert(app.UIFigure, ['Failed to create slot folder: ' e.message], 'Folder Error', 'Icon', 'error');
                return;
            end
            
            try
                slotData = {};
                if exist(slotFile, 'file')
                    slotData = readcell(slotFile);
                end
                if isempty(slotData) || ~(iscell(slotData) && size(slotData,2) >= 1)
                    slotData = {'SlotName'};
                end
                existingSlots = string(slotData(2:end, 1));
                if ~any(existingSlots == newSlot)
                    slotData(end+1, :) = {char(newSlot)};
                    writecell(slotData, slotFile);
                end
            catch e
                uialert(app.UIFigure, ['Error updating slots.xlsx: ' e.message], 'File Error', 'Icon', 'error');
            end
            
            try
                if exist(slotFile, 'file')
                    allData = readcell(slotFile);
                    if size(allData,1) > 1
                        rawSlots = allData(2:end, 1);
                        rawSlots = rawSlots(~cellfun('isempty', rawSlots));
                        app.SelectSlot.Items = string(rawSlots);
                    else
                        app.SelectSlot.Items = {};
                    end
                else
                    app.SelectSlot.Items = {};
                end
            catch
                app.SelectSlot.Items = {};
            end
            
            uialert(app.UIFigure, ['Slot "' char(newSlot) '" created.'], 'Success', 'Icon', 'success');

        end

        % Button pushed function: SelectSlotButton
        function SelectSlotButtonPushed(app, event)
 if isempty(app.LoggedInUser)
                uialert(app.UIFigure, 'Please log in first.', 'Error', 'Icon', 'warning');
                return;
            end

            if isempty(app.SelectSlot.Items)
                uialert(app.UIFigure, 'No slots available. Please create a slot first.', 'No Slots', 'Icon', 'warning');
                return;
            end

            % Make dropdown and label visible
            app.SelectSlot.Visible = 'on';
            app.SlotDropdownDropDownLabel.Visible = 'on';

            % Change button text and assign callback
            app.SelectSlotButton.Text = 'Confirm Selection';
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @app.ConfirmSlotSelection);
        end

        % Button pushed function: TakeAttendanceButton
        function TakeAttendanceButtonPushed(app, event)
       % --- PRE-CHECKS ---
            if isempty(app.SelectedSlot)
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return;
            end
            
            if isempty(app.cam)
                % Close stray figures
                try
                    figs = findall(0, 'Type', 'figure');
                    for i = 1:numel(figs)
                        if figs(i) ~= app.UIFigure, try close(figs(i)); catch, end; end
                    end
                    uifigs = findall(0, 'Type', 'uifigure');
                    for i = 1:numel(uifigs)
                        if uifigs(i) ~= app.UIFigure, try close(uifigs(i)); catch, end; end
                    end
                catch
                end
            end
            
            % Check if FaceNet model is loaded
            if isempty(app.facenetModel)
                uialert(app.UIFigure, 'FaceNet model not loaded. Please restart the application.', 'Model Error', 'Icon', 'error');
                return;
            end
            
            % --- SETUP ---
            app.AttendancePanel.Visible = 'on';
            % ensure axes are fresh
            try
                cla(app.CameraAxes);
            catch
            end

            app.CameraAxes.Visible = 'on';
            ax = app.getSafeCameraAxes();

            % Initialize webcam and detector
            try
                if ~app.initCamera()
                    return;
                end
            catch
                uialert(app.UIFigure, 'Unable to access webcam.', 'Camera Error', 'Icon', 'error');
                return;
            end
            
            app.isAttendanceRunning = true;
            username = app.LoggedInUser;
            slot = app.SelectedSlot;
            
            % Use absolute path - FIXED
            slotPath = fullfile(app.getBaseFolder(), app.attendanceDataPath, username, slot);
            studentBase = fullfile(slotPath, 'students');

            if ~exist(studentBase, 'dir')
                uialert(app.UIFigure, 'Student data folder not found for this slot.', 'Folder Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end

            % --- ATTENDANCE FILE ---
            todayDate = datestr(now, 'yyyy-mm-dd');
            attendanceFile = fullfile(slotPath, ['attendance_' todayDate '.xlsx']);

            % Create attendance file with headers if missing
            if ~exist(attendanceFile, 'file')
                headers = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                writecell(headers, attendanceFile);
            else
                try
                    checkTbl = readtable(attendanceFile, 'TextType', 'string');
                    expectedHeaders = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                    if ~all(ismember(expectedHeaders, checkTbl.Properties.VariableNames))
                        % Add missing headers
                        oldData = readcell(attendanceFile);
                        writecell([expectedHeaders; oldData(2:end,:)], attendanceFile);
                    end
                catch
                    % If file corrupt, recreate with headers
                    headers = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                    writecell(headers, attendanceFile);
                end
            end

            % Load already marked students to prevent duplicates
            app.MarkedStudents = {};
            try
                attendanceData = readtable(attendanceFile, 'TextType', 'string');
                if ~isempty(attendanceData) && any(strcmp(attendanceData.Properties.VariableNames, 'RegistrationNumber'))
                    existingRegNos = attendanceData.RegistrationNumber;
                    existingRegNos = existingRegNos(~ismissing(existingRegNos) & existingRegNos ~= "");
                    app.MarkedStudents = cellstr(unique(existingRegNos));
                end
            catch
            end

            % Load student database - FIXED PATH
            studentsFile = fullfile(slotPath, 'students.xlsx');
            if ~exist(studentsFile, 'file')
                uialert(app.UIFigure, 'students.xlsx not found. Please add students to this slot.', 'File Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end
            try
                studentsTbl = readtable(studentsFile, 'TextType', 'string');
            catch
                uialert(app.UIFigure, 'Could not read students.xlsx. Check format.', 'File Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end

            % --- ATTENDANCE LOOP ---
            popupCooldown = 5; % seconds
            lastPopupTimeMap = containers.Map; % key: regNo, value: datetime
            
            % Reset multi-frame validation
            app.lastDetections = containers.Map;
            
            % Display instructions
            title(ax, 'Position your face in the frame', 'Color', 'white');
            pause(2);

            % Confidence counter for multi-frame validation
            confidenceCounter = containers.Map;
            
            while app.isAttendanceRunning
                frame = snapshot(app.cam);
                
                % Display the original frame
                imshow(frame, 'Parent', ax);
                drawnow limitrate;

                % Detect and preprocess face
                [faceImg, success] = app.detectAndPreprocessFace(frame);
                
                if ~success
                    title(ax, 'No face detected - position face in center', 'Color', 'red');
                    pause(0.1);
                    continue;
                end
                
                % Extract features
                [queryFeatures, success] = app.extractEnhancedFeatures(faceImg);
                
                if ~success
                    title(ax, 'Feature extraction failed', 'Color', 'red');
                    pause(0.1);
                    continue;
                end
                
                % Find best match with enhanced matching
                [bestStudent, bestScore, regNo, confidence] = app.findEnhancedMatch(queryFeatures, studentsTbl, studentBase);
                
                nowT = datetime('now');
                
                % Debug information
                if app.debugMode
                    app.logMessage(sprintf('Best match: %s, Score: %.4f, Confidence: %s', bestStudent, bestScore, confidence));
                end
                
                % Handle different confidence levels
                switch confidence
                    case "High"
                        % High confidence - immediate recognition
                        title(ax, sprintf('High confidence: %s (%.2f)', bestStudent, bestScore), 'Color', 'green');
                        
                        % Skip if already marked
                        if any(strcmpi(app.MarkedStudents, char(regNo)))
                            if ~isKey(lastPopupTimeMap, char(regNo)) || seconds(nowT - lastPopupTimeMap(char(regNo))) > popupCooldown
                                autoCloseAlert(sprintf('Attendance already taken for: %s (%s)', bestStudent, regNo), 'Info', 'info', 2);
                                lastPopupTimeMap(char(regNo)) = nowT;
                            end
                        else
                            % Mark attendance immediately for high confidence
                            app.MarkedStudents{end+1} = char(regNo);
                            timeStr = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                            lat = "Unknown"; lon = "Unknown";
                            row = {char(bestStudent), char(regNo), timeStr, lat, lon};
                            try
                                writecell(row, attendanceFile, 'WriteMode', 'append');
                            catch
                                old = readcell(attendanceFile);
                                new = [old; row];
                                writecell(new, attendanceFile);
                            end
                            title(ax, sprintf('Marked: %s', bestStudent), 'Color', 'green');
                            autoCloseAlert(sprintf('Attendance marked: %s (%s)', bestStudent, regNo), 'Success', 'success', 2);
                            lastPopupTimeMap(char(regNo)) = nowT;
                        end
                        
                    case "Medium"
                        % Medium confidence - require 2 consistent detections
                        title(ax, sprintf('Medium confidence: %s (%.2f)', bestStudent, bestScore), 'Color', 'yellow');
                        
                        if any(strcmpi(app.MarkedStudents, char(regNo)))
                            if ~isKey(lastPopupTimeMap, char(regNo)) || seconds(nowT - lastPopupTimeMap(char(regNo))) > popupCooldown
                                autoCloseAlert(sprintf('Attendance already taken for: %s (%s)', bestStudent, regNo), 'Info', 'info', 2);
                                lastPopupTimeMap(char(regNo)) = nowT;
                            end
                        else
                            % Multi-frame validation
                            key = char(regNo);
                            if ~isKey(confidenceCounter, key)
                                confidenceCounter(key) = 1;
                            else
                                confidenceCounter(key) = confidenceCounter(key) + 1;
                            end
                            
                            % Mark attendance after 2 consistent detections
                            if confidenceCounter(key) >= 2
                                app.MarkedStudents{end+1} = char(regNo);
                                timeStr = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                                lat = "Unknown"; lon = "Unknown";
                                row = {char(bestStudent), char(regNo), timeStr, lat, lon};
                                try
                                    writecell(row, attendanceFile, 'WriteMode', 'append');
                                catch
                                    old = readcell(attendanceFile);
                                    new = [old; row];
                                    writecell(new, attendanceFile);
                                end
                                title(ax, sprintf('Marked: %s', bestStudent), 'Color', 'green');
                                autoCloseAlert(sprintf('Attendance marked: %s (%s)', bestStudent, regNo), 'Success', 'success', 2);
                                lastPopupTimeMap(char(regNo)) = nowT;
                                confidenceCounter(key) = 0;
                            else
                                title(ax, sprintf('Verifying: %s (%d/2)', bestStudent, confidenceCounter(key)), 'Color', 'yellow');
                            end
                        end
                        
                    case "Low"
                        % Low confidence - require 3 consistent detections
                        title(ax, sprintf('Low confidence: %s (%.2f)', bestStudent, bestScore), 'Color', 'yellow');
                        
                        if any(strcmpi(app.MarkedStudents, char(regNo)))
                            if ~isKey(lastPopupTimeMap, char(regNo)) || seconds(nowT - lastPopupTimeMap(char(regNo))) > popupCooldown
                                autoCloseAlert(sprintf('Attendance already taken for: %s (%s)', bestStudent, regNo), 'Info', 'info', 2);
                                lastPopupTimeMap(char(regNo)) = nowT;
                            end
                        else
                            % Multi-frame validation
                            key = char(regNo);
                            if ~isKey(confidenceCounter, key)
                                confidenceCounter(key) = 1;
                            else
                                confidenceCounter(key) = confidenceCounter(key) + 1;
                            end
                            
                            % Mark attendance after 3 consistent detections
                            if confidenceCounter(key) >= 3
                                app.MarkedStudents{end+1} = char(regNo);
                                timeStr = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                                lat = "Unknown"; lon = "Unknown";
                                row = {char(bestStudent), char(regNo), timeStr, lat, lon};
                                try
                                    writecell(row, attendanceFile, 'WriteMode', 'append');
                                catch
                                    old = readcell(attendanceFile);
                                    new = [old; row];
                                    writecell(new, attendanceFile);
                                end
                                title(ax, sprintf('Marked: %s', bestStudent), 'Color', 'green');
                                autoCloseAlert(sprintf('Attendance marked: %s (%s)', bestStudent, regNo), 'Success', 'success', 2);
                                lastPopupTimeMap(char(regNo)) = nowT;
                                confidenceCounter(key) = 0;
                            else
                                title(ax, sprintf('Verifying: %s (%d/3)', bestStudent, confidenceCounter(key)), 'Color', 'yellow');
                            end
                        end
                        
                    otherwise
                        % Very low confidence or no match
                        if isempty(bestStudent)
                            title(ax, 'Unknown face - not in database', 'Color', 'red');
                        else
                            title(ax, sprintf('Very low confidence: %.2f', bestScore), 'Color', 'red');
                        end
                end
                
                pause(0.2);
            end
            
            % --- NESTED FUNCTIONS ---
            function autoCloseAlert(msg, title, icon, delay)
                uialert(app.UIFigure, msg, title, 'Icon', icon);
                t = timer('StartDelay', delay, 'TimerFcn', @(~,~) closeAlertByTitle(title));
                start(t);
            end

            function closeAlertByTitle(title)
                figs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(figs)
                    if strcmp(figs(i).Name, title)
                        try close(figs(i)); catch, end
                    end
                end
            end
        end

        % Button pushed function: StopAttendanceButton
        function StopAttendanceButtonPushed(app, event)
 % Check if slot is selected first
            if isempty(app.SelectedSlot)
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return;
            end
            
            app.isAttendanceRunning = false;
            try
                if ~isempty(app.cam)
                    delete(app.cam);
                    app.cam = [];
                end
            catch
                try clear app.cam; app.cam = []; catch, end
            end
            app.AttendancePanel.Visible = 'off';
            try
                cla(app.CameraAxes);
            catch
            end
            uialert(app.UIFigure, 'Attendance session stopped and camera released.', 'Info', 'Icon', 'success');
        end

        % Button down function: MainPanel
        function MainPanelButtonDown(app, event)
            
        end

        % Button pushed function: AddStudentButton
        function AddStudentButtonPushed(app, event)

    % --- PRE-CHECKS ---
    if isempty(app.LoggedInUser)
        uialert(app.UIFigure, 'Please log in first.', 'Error', 'Icon', 'warning');
        return;
    end
    
    if isempty(app.SelectedSlot)
        uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
        return;
    end
    
    % --- GET STUDENT INFO ---
    % Create enhanced input dialog for student details
    prompt = {'Student Name:', 'Registration Number:', 'Additional Notes (optional):'};
    dlgtitle = 'Add New Student';
    dims = [1, 35; 1, 35; 3, 35];
    definput = {'', '', ''};
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    % Check if user cancelled
    if isempty(answer)
        return;
    end
    
    % Validate inputs
    studentName = strtrim(answer{1});
    regNumber = strtrim(answer{2});
    notes = strtrim(answer{3});
    
    if isempty(studentName) || isempty(regNumber)
        uialert(app.UIFigure, 'Name and registration number are required.', 'Input Error', 'Icon', 'warning');
        return;
    end
    
    invalidChars = ['<', '>', ':', '"', '/', '\', '|', '?', '*'];
    if any(ismember(studentName, invalidChars))
        uialert(app.UIFigure, 'Name contains invalid characters: \ / : * ? " < > |', 'Invalid Name', 'Icon', 'warning');
        return;
    end
    
    % --- SETUP PATHS ---
    baseFolder = app.getBaseFolder();
    username = app.LoggedInUser;
    slotName = app.SelectedSlot;
    
    slotPath = fullfile(baseFolder, app.attendanceDataPath, username, slotName);
    studentsFile = fullfile(slotPath, 'students.xlsx');
    studentBase = fullfile(slotPath, 'students');
    studentFolder = fullfile(studentBase, studentName);
    
    if app.debugMode
        app.logMessage(sprintf('Student folder path: %s', studentFolder));
        app.logMessage(sprintf('Students file path: %s', studentsFile));
    end
    
    if exist(studentFolder, 'dir')
        uialert(app.UIFigure, 'A student with this name already exists in this slot.', 'Duplicate Entry', 'Icon', 'warning');
        return;
    end
    
    if exist(studentsFile, 'file')
        try
            existingStudents = readtable(studentsFile, 'TextType', 'string');
            if any(existingStudents.RegistrationNumber == regNumber)
                uialert(app.UIFigure, 'This registration number already exists in this slot.', 'Duplicate Entry', 'Icon', 'warning');
                return;
            end
        catch
            % File might be corrupt, we'll recreate it later
        end
    end
    
    % --- INITIALIZE CAMERA ---
    if isempty(app.cam) && ~app.initCamera()
        uialert(app.UIFigure, 'Failed to initialize camera.', 'Camera Error', 'Icon', 'error');
        return;
    end
    
    % --- MULTI-ANGLE ENROLLMENT WITH BETTER GUIDANCE ---
    app.AttendancePanel.Visible = 'on';
    ax = app.getSafeCameraAxes();
    cla(ax);
    
    % Display enrollment instructions
    instructions = {
        'ENROLLMENT INSTRUCTIONS:';
        '1. Ensure good lighting on your face';
        '2. Remove glasses if possible';
        '3. Look directly at the camera';
        '4. Follow the instructions for different angles';
        '5. Stay still during capture'
    };
    
    text(ax, 0.5, 0.7, instructions, 'Color', 'white', 'FontSize', 12, ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    title(ax, 'Preparing to capture images...', 'Color', 'white');
    pause(3);
    
    totalImages = 10;
    capturedImages = cell(1, totalImages);
    imageCount = 1;
    
    % Capture instructions for different angles with visual cues
    angles = {
        'Look straight at camera', [0.5, 0.7];
        'Turn head slightly left', [0.3, 0.7];
        'Turn head slightly right', [0.7, 0.7];
        'Look slightly up', [0.5, 0.6];
        'Look slightly down', [0.5, 0.8]
    };
    
    imagesPerAngle = 2;
    
    for angleIdx = 1:length(angles)
        angle = angles{angleIdx, 1};
        pos = angles{angleIdx, 2};
        
        % Display angle instruction with visual cue
        cla(ax);
        faceOutline = rectangle(ax, 'Position', [0.25, 0.25, 0.5, 0.5], 'Curvature', [1, 1], ...
            'EdgeColor', 'white', 'LineWidth', 2);
        directionMarker = text(ax, pos(1), pos(2), '↑', 'Color', 'green', 'FontSize', 24, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
        title(ax, sprintf('Position: %s', angle), 'Color', 'white');
        pause(1.5); % Give time to position
        
        % Capture images for this angle
        for i = 1:imagesPerAngle
            % Countdown before capture
            for count = 3:-1:1
                countdownText = text(ax, 0.5, 0.3, sprintf('%d', count), 'Color', 'yellow', ...
                    'FontSize', 36, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                pause(1);
                delete(countdownText);
            end
            
            % Capture frame
            frame = snapshot(app.cam);
            capturedImages{imageCount} = frame;
            imageCount = imageCount + 1;
            
            % Show preview with checkmark
            imshow(frame, 'Parent', ax);
            text(ax, 0.9, 0.1, '✓', 'Color', 'green', 'FontSize', 24, ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            title(ax, sprintf('Captured %d/%d (%s)', (angleIdx-1)*imagesPerAngle+i, totalImages, angle), 'Color', 'green');
            pause(0.5); % Brief pause to show confirmation
        end
    end
    
    title(ax, 'Processing images...', 'Color', 'white');
    
    % --- PROCESS IMAGES AND SAVE FEATURES ---
    if ~exist(studentFolder, 'dir')
        mkdir(studentFolder);
    end
    
    % Save enrollment notes if provided
    if ~isempty(notes)
        notesFile = fullfile(studentFolder, 'enrollment_notes.txt');
        fid = fopen(notesFile, 'w');
        fprintf(fid, 'Enrollment notes: %s\n', notes);
        fclose(fid);
    end
    
    featuresExtracted = 0;
    qualityIssues = 0;
    detectionIssues = 0;
    
    for i = 1:totalImages
        frame = capturedImages{i};
        
        % Debug: Save original frame
        debugFile = fullfile(studentFolder, sprintf('debug_original_%d.jpg', i));
        imwrite(frame, debugFile);
        
        % Detect and preprocess face
        [faceImg, success] = app.detectAndPreprocessFace(frame);
        
        if ~success
            if app.debugMode
                app.logMessage(sprintf('Image %d: No face detected', i));
            end
            detectionIssues = detectionIssues + 1;
            continue;
        end
        
        % Debug: Save detected face
        debugFaceFile = fullfile(studentFolder, sprintf('debug_face_%d.jpg', i));
        imwrite(faceImg, debugFaceFile);
        
        % Check face quality
        quality = app.checkFaceQuality(faceImg);
        if quality == 0
            if app.debugMode
                app.logMessage(sprintf('Image %d: Poor quality face', i));
            end
            qualityIssues = qualityIssues + 1;
            continue;
        end
        
        % Extract features
        [combinedFeatures, success] = app.extractEnhancedFeatures(faceImg);
        
        if ~success
            if app.debugMode
                app.logMessage(sprintf('Image %d: Feature extraction failed', i));
            end
            continue;
        end
        
        % Save combined features
        featureFile = fullfile(studentFolder, sprintf('combinedFeatures_%d.mat', i));
        save(featureFile, 'combinedFeatures');
        
        % Also save the face image for reference
        faceFile = fullfile(studentFolder, sprintf('face_%d.jpg', i));
        imwrite(imresize(faceImg, [160, 160]), faceFile);
        
        featuresExtracted = featuresExtracted + 1;
    end
    
    if featuresExtracted == 0
        rmdir(studentFolder, 's');
        errorMsg = sprintf(['No usable faces detected. %d images had detection issues, %d images had quality issues. ', ...
            'Please ensure good lighting and follow the enrollment instructions.'], detectionIssues, qualityIssues);
        uialert(app.UIFigure, errorMsg, 'Face Detection Failed', 'Icon', 'error');
        
        % Show troubleshooting tips
        tips = {
            'TROUBLESHOOTING TIPS:';
            '1. Ensure good lighting on the face';
            '2. Avoid backlighting (bright light behind the subject)';
            '3. Make sure the face is clearly visible';
            '4. Try again in a different environment';
            '5. Check if the camera lens is clean'
        };
        uialert(app.UIFigure, tips, 'Enrollment Tips', 'Icon', 'info');
        return;
    end
    
    % --- UPDATE STUDENTS DATABASE ---
    newEntry = {studentName, regNumber, notes};
    
    if exist(studentsFile, 'file')
        try
            existingData = readcell(studentsFile);
            
            % Check if we need to add the Notes column
            if isempty(existingData) || size(existingData, 2) < 3
                if isempty(existingData)
                    existingData = {'Name', 'RegistrationNumber', 'Notes'};
                else
                    existingData(1, end+1) = {'Notes'};
                    % Fill empty cells for existing rows
                    for row = 2:size(existingData, 1)
                        if size(existingData, 2) > row
                            existingData{row, end} = '';
                        end
                    end
                end
            end
            
            % Append new entry
            writecell([existingData; newEntry], studentsFile);
        catch
            % File might be corrupt, recreate it
            headers = {'Name', 'RegistrationNumber', 'Notes'};
            writecell([headers; newEntry], studentsFile);
        end
    else
        % Create new file with headers
        headers = {'Name', 'RegistrationNumber', 'Notes'};
        writecell([headers; newEntry], studentsFile);
    end
    
    % --- CLEANUP AND CONFIRMATION ---
    app.releaseCamera();
    app.AttendancePanel.Visible = 'off';
    cla(ax);
    
    uialert(app.UIFigure, ...
        sprintf('Student "%s" added successfully with %d training images.', studentName, featuresExtracted), ...
        'Success', 'Icon', 'success');

        end

        % Value changed function: ShowPasswordCheckBox
        function ShowPasswordCheckBoxValueChanged(app, event)
  if app.ShowPasswordCheckBox.Value
                app.Password.Value = app.realPassword;
            else
                app.Password.Value = repmat('*', 1, length(app.realPassword));
  end
            
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
    % Release camera resources
            app.releaseCamera();
            
            % Reset application state
            app.LoggedInUser = [];
            app.SelectedSlot = [];
            app.MarkedStudents = {};
            app.isAttendanceRunning = false;
            
            % Reset UI components
            app.Username.Value = '';
            app.Password.Value = '';
            app.realPassword = '';
            app.SelectSlot.Items = {}; % Clear dropdown items
            app.SelectSlot.Visible = 'off';
            app.SlotDropdownDropDownLabel.Visible = 'off';
            
            % Reset button text and functionality
            app.SelectSlotButton.Text = 'Select Slot';
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSlotButtonPushed);
            
            % Show/hide panels
            app.LoginPanel.Visible = 'on';
            app.LoginPanel.Enable = 'on';
            app.MainPanel.Visible = 'off';
            app.AttendancePanel.Visible = 'off';
            
            % Clear camera axes
            cla(app.CameraAxes);
            
            uialert(app.UIFigure, 'Successfully logged out.', 'Logout', 'Icon', 'success');
        end

        % Value changing function: Password
        function PasswordValueChanging(app, event)
       % Real-time password masking
            newValue = event.Value;
            currentReal = app.realPassword;
            
            if length(newValue) > length(currentReal)
                % Character added
                addedChar = newValue(end);
                app.realPassword = [currentReal addedChar];
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            elseif length(newValue) < length(currentReal)
                % Character deleted
                app.realPassword = currentReal(1:end-1);
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            else
                % Text was pasted or replaced - handle this case
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginPanel = uipanel(app.UIFigure);
            app.LoginPanel.Title = 'LoginPanel';
            app.LoginLabel = uilabel(app.LoginPanel);
            app.LoginLabel.Text = 'Login';
            app.PasswordLabel = uilabel(app.LoginPanel);
            app.PasswordLabel.Text = 'Password ';
            app.Username = uieditfield(app.LoginPanel, 'text');
            app.Password = uieditfield(app.LoginPanel, 'text');
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            app.LoginButton = uibutton(app.LoginPanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.NewuserSignupButton = uibutton(app.LoginPanel, 'push');
            app.NewuserSignupButton.ButtonPushedFcn = createCallbackFcn(app, @NewuserSignupButtonPushed, true);
            app.NewuserSignupButton.Text = 'New user ? Signup';
            app.ShowPasswordCheckBox = uicheckbox(app.LoginPanel);
            app.ShowPasswordCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPasswordCheckBoxValueChanged, true);
            app.ShowPasswordCheckBox.Text = 'ShowPassword';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'MainPanel';
            app.MainPanel.ButtonDownFcn = createCallbackFcn(app, @MainPanelButtonDown, true);
            app.TakeAttendanceButton = uibutton(app.MainPanel, 'push');
            app.TakeAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @TakeAttendanceButtonPushed, true);
            app.TakeAttendanceButton.Text = 'Take Attendance';
            app.AttendancePanel = uipanel(app.MainPanel);
            app.AttendancePanel.Title = 'Attendance';
            app.CameraAxes = uiaxes(app.AttendancePanel);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.StopAttendanceButton = uibutton(app.MainPanel, 'push');
            app.StopAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @StopAttendanceButtonPushed, true);
            app.StopAttendanceButton.Text = 'Stop Attendance';
            app.AddStudentButton = uibutton(app.MainPanel, 'push');
            app.AddStudentButton.ButtonPushedFcn = createCallbackFcn(app, @AddStudentButtonPushed, true);
            app.AddStudentButton.Text = 'Add Student';
            app.SlotDropdownDropDownLabel = uilabel(app.MainPanel);
            app.SlotDropdownDropDownLabel.Text = 'SlotDropdown';
            app.SelectSlot = uidropdown(app.MainPanel);
            app.SelectSlotButton = uibutton(app.MainPanel, 'push');
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSlotButtonPushed, true);
            app.SelectSlotButton.Text = 'Select Slot';
            app.CreateSlotButton = uibutton(app.MainPanel, 'push');
            app.CreateSlotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSlotButtonPushed, true);
            app.CreateSlotButton.Text = 'CreateSlotButton';
            app.LogoutButton = uibutton(app.MainPanel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttendanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end